classdef itaOptitrackVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        LoadDataButton                 matlab.ui.control.Button
        DataFileTextAreaLabel          matlab.ui.control.Label
        DataFileTextArea               matlab.ui.control.TextArea
        DPlotSettingsPanel             matlab.ui.container.Panel
        GridLayoutPlotButtons          matlab.ui.container.GridLayout
        plotDataButton                 matlab.ui.control.StateButton
        Plot3DSwitch                   matlab.ui.control.Switch
        FramestoPlotLabel              matlab.ui.control.Label
        CoordinatesystemLabel          matlab.ui.control.Label
        CoordinateSystemSwitch         matlab.ui.control.Switch
        KeepAxisMAXCheckBox            matlab.ui.control.CheckBox
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        showGridCheckBox               matlab.ui.control.CheckBox
        UpdatePlotsButton              matlab.ui.control.Button
        PlotSettings_2D                matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        RollCheckBox                   matlab.ui.control.CheckBox
        PitchCheckBox                  matlab.ui.control.CheckBox
        YawCheckBox                    matlab.ui.control.CheckBox
        LegendCheckBox                 matlab.ui.control.CheckBox
        DatasetDropDown                matlab.ui.control.DropDown
        Panel                          matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        AnimateButton                  matlab.ui.control.Button
        AnimationRunningLamp           matlab.ui.control.Lamp
        StopButton                     matlab.ui.control.Button
        AnimationStepSizeFramesSliderLabel  matlab.ui.control.Label
        AnimationStepSizeFramesSlider  matlab.ui.control.Slider
        TracePositonCheckBox           matlab.ui.control.CheckBox
        UpVectorCheckBox               matlab.ui.control.CheckBox
        RightPanel                     matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        UIAxes2D                       matlab.ui.control.UIAxes
        UIAxes3D                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    %Constants
    properties(Constant)
        lineColor = lines;
        selectColor = [74, 186, 212]/255; %in 2D Plot -> selected area for 3D plot Highlight color
        animationFrameColor = [15, 145, 45]/255; %currently animated frame line corlor (in 2D Plot)
        disabledUIElementsDuringAnimation = {...
            'LoadDataButton',...
            'CoordinateSystemSwitch',...
            'Plot3DSwitch',...
            'KeepAxisMAXCheckBox',...
            'showGridCheckBox',...
            'plotDataButton',...
            'YawCheckBox',...
            'PitchCheckBox' ...
            'RollCheckBox',...
            'LegendCheckBox',...
            'DatasetDropDown',...
            'LoadDataButton'};
        
        disabledUIElementsDuringLoad = {...
            'CoordinateSystemSwitch',...
            'Plot3DSwitch',...
            'KeepAxisMAXCheckBox',...
            'showGridCheckBox',...
            'plotDataButton',...
            'YawCheckBox',...
            'PitchCheckBox' ...
            'RollCheckBox',...
            'LegendCheckBox',...
            'DatasetDropDown',...
            'AnimateButton',...
            'StopButton',...
            'AnimationStepSizeFramesSlider',...
            'TracePositonCheckBox',...
            'UpVectorCheckBox'};
        
    end
    
    %Variables
    properties(Access = private)
        %loaded data
        logData  = struct(); % Description
        fileName = '';      %filenae for loaded data
        filePath = mfilename;
        
        %plot setings
        selectedFrames = [];%frames selected for 3D plot/animation
        selectionState = 0; %0 none, 1: start Selected, 2: Start and end
        
        %max axis limits reached while option to keep was selected
        xLimMax = [0,0];
        yLimMax = [0,0];
        zLimMax = [0,0];
        
        %bool to stop animation, checked in animation loop before each plot
        AnimationIsRunning = false;
        stopAnimation = false;
    end
    
    %dependent Variables
    properties (Access = public,Dependent=true)
        nRigidBodys;          %number of rigid bodys in dataset
        nDataPoints;          %number of frames in each dataset
        %         nRigidBodys2plot;     %how many bodys are selected -> remove
        plotDataset2D;        %which rigidBody to show in 2D plot
        convertOpenGL2Matlab; %bool: show data in openGL or Matlab coordinates
    end
    
    %plot functions
    methods
        function clearAllPlots(app)
            clear2Dplot(app);
            clear3Dplot(app);
        end
        
        function clear2Dplot(app)
            cla(app.UIAxes2D)
        end
        
        function clear3Dplot(app)
            cla(app.UIAxes3D);
        end
        
        function updateAllPlots(app)
            draw2DPlot(app);
            draw3DPlot(app);
        end
        
        function draw3DPlot(app)
            if app.AnimationIsRunning
                return;
            end
            %get plot frame limits
            frameIdx = app.framesToPlotIdx;
            
            %reset plot
            clear3Dplot(app);
            app.UIAxes3D.NextPlot = 'add';
            app.UIAxes3D.XLimMode = 'auto';
            app.UIAxes3D.YLimMode = 'auto';
            app.UIAxes3D.ZLimMode = 'auto';
            
            %make new plot
            for iDataset = 1:app.nRigidBodys
                if app.plotDataButton(iDataset).Value == false
                    continue;
                end
                
                if app.convertOpenGL2Matlab
                    scatter3(app.UIAxes3D,...
                        -app.logData(iDataset).position.z(frameIdx),...
                        -app.logData(iDataset).position.x(frameIdx),...
                        app.logData(iDataset).position.y(frameIdx),...
                        [],app.lineColor(iDataset,:))
                else
                    scatter3(app.UIAxes3D,...
                        app.logData(iDataset).position.x(frameIdx),...
                        app.logData(iDataset).position.y(frameIdx),...
                        app.logData(iDataset).position.z(frameIdx),...
                        [],app.lineColor(iDataset,:))
                end
            end
            
            %update axis limits
            if app.KeepAxisMAXCheckBox.Value
                %keep max limits to better compare data
                app.UIAxes3D.XLim = [min([app.UIAxes3D.XLim,app.xLimMax]),max([app.UIAxes3D.XLim,app.xLimMax])];
                app.UIAxes3D.YLim = [min([app.UIAxes3D.YLim,app.yLimMax]),max([app.UIAxes3D.YLim,app.yLimMax])];
                app.UIAxes3D.ZLim = [min([app.UIAxes3D.ZLim,app.zLimMax]),max([app.UIAxes3D.ZLim,app.zLimMax])];
            end
            %update stored max values
            app.xLimMax = app.UIAxes3D.XLim;
            app.yLimMax = app.UIAxes3D.YLim;
            app.zLimMax = app.UIAxes3D.ZLim;
            
            % show Grid
            if app.showGridCheckBox.Value
                app.UIAxes3D.XGrid = 'on';
                app.UIAxes3D.YGrid = 'on';
                app.UIAxes3D.ZGrid = 'on';
            else
                app.UIAxes3D.XGrid = 'off';
                app.UIAxes3D.YGrid = 'off';
                app.UIAxes3D.ZGrid = 'off';
            end
        end
        
        function draw2DPlot(app)
            clear2Dplot(app);
            app.UIAxes2D.NextPlot = 'add';
            
            plotRoll = app.RollCheckBox.Value;
            plotPitch = app.PitchCheckBox.Value;
            plotYaw = app.YawCheckBox.Value;
            showLegend = app.LegendCheckBox.Value;
            iDat = app.plotDataset2D;
            if plotRoll
                plot(app.UIAxes2D,app.logData(iDat).orientation.roll_deg,...
                    'DisplayName','Roll','Color',app.lineColor(1,:));
            end
            if plotPitch
                plot(app.UIAxes2D,app.logData(iDat).orientation.pitch_deg,...
                    'DisplayName','Pitch','Color',app.lineColor(2,:));
            end
            if plotYaw
                plot(app.UIAxes2D,app.logData(iDat).orientation.yaw_deg,...
                    'DisplayName','Yaw','Color',app.lineColor(3,:));
            end
            
            if showLegend
                legend(app.UIAxes2D,'show','Location','best')
            else
                legend(app.UIAxes2D,'hide')
            end
            %update Axis limits
            app.UIAxes2D.XLim = [0,app.nDataPoints];
            app.UIAxes2D.YLimMode = 'auto';
            app.drawSelectionHighlightIn2DPlot();
        end
        
        function clearSelectionHighlightIn2DPlot(app)
            %remove selection highlight in 2D plot
            delete(findall(app.UIAxes2D,'Tag','Select'));
        end
        
        function drawSelectionHighlightIn2DPlot(app)
            app.clearSelectionHighlightIn2DPlot;
            yDat = app.UIAxes2D.YLim;
            xDat = sort(app.selectedFrames);
            switch app.selectionState
                case 0
                    %clear old selection
                case 1
                    line(app.UIAxes2D,[xDat, xDat], yDat,'Color',app.selectColor,'Tag','Select','DisplayName','Animation Start');
                    app.UIAxes2D.Children = circshift(app.UIAxes2D.Children ,-1);
                case 2
                    rectangle(app.UIAxes2D,...
                        'Position',[xDat(1) yDat(1) xDat(2)-xDat(1) yDat(2)-yDat(1)],...
                        'FaceColor',app.selectColor,...
                        'EdgeColor',app.selectColor,...
                        'Tag','Select');
                    app.UIAxes2D.Children = circshift(app.UIAxes2D.Children ,-1);
            end
            
            %sort line Object and move Selection to the back
            
            app.UIAxes2D.YLim = yDat;
        end
        
        function drawTrace(app,dataSetIdx,frameIdx)
            [xTrace,yTrace,zTrace] = app.convertTo3DPlotCoordinateSystem(...
                app.logData(dataSetIdx).position.x(frameIdx),...
                app.logData(dataSetIdx).position.y(frameIdx),...
                app.logData(dataSetIdx).position.z(frameIdx));
            
            plot3(app.UIAxes3D,xTrace,yTrace,zTrace,...
                'Color',app.lineColor(dataSetIdx,:),...
                'LineStyle',":",...
                'LineWidth',1.2)
        end
        
        function drawPositionandViewVector(app, dataSetIdx, frameIdx)
            persistent viewHandle;
            persistent positionHandle;
            persistent upVectorHandle
            plotUpVector = app.UpVectorCheckBox.Value;
            
            % set plot data
            [xPos,yPos,zPos] = app.convertTo3DPlotCoordinateSystem(...
                app.logData(dataSetIdx).position.n(frameIdx).x,...
                app.logData(dataSetIdx).position.n(frameIdx).y,...
                app.logData(dataSetIdx).position.n(frameIdx).z);
            [xView,yView,zView] = app.convertTo3DPlotCoordinateSystem(...
                app.logData(dataSetIdx).orientation.n(frameIdx).view(:,1), ...
                app.logData(dataSetIdx).orientation.n(frameIdx).view(:,2), ...
                app.logData(dataSetIdx).orientation.n(frameIdx).view(:,3));
            
            xPlot = [xPos,xPos+xView];
            yPlot = [yPos,yPos+yView];
            zPlot = [zPos,zPos+zView];
            
            if plotUpVector
                [xUp,yUp,zUp] = app.convertTo3DPlotCoordinateSystem(...
                    app.logData(dataSetIdx).orientation.n(frameIdx).up(:,1), ...
                    app.logData(dataSetIdx).orientation.n(frameIdx).up(:,2), ...
                    app.logData(dataSetIdx).orientation.n(frameIdx).up(:,3));
                xPlotUp = [xPos,xPos+xUp];
                yPlotUp = [yPos,yPos+yUp];
                zPlotUp = [zPos,zPos+zUp];
            end
            
            
            if isgraphics(positionHandle)
                positionHandle.XData = xPos;
                positionHandle.YData = yPos;
                positionHandle.ZData = zPos;
            else
                positionHandle = scatter3(app.UIAxes3D,...
                    xPos,yPos,zPos,...
                    [],app.lineColor(dataSetIdx,:),...
                    'Tag','Position');
            end
            
            if isgraphics(viewHandle)
                % if line already exist, update
                viewHandle.XData = xPlot;
                viewHandle.YData = yPlot;
                viewHandle.ZData = zPlot;
            else
                viewHandle = line(app.UIAxes3D,xPlot,yPlot,zPlot,...
                    'LineWidth',1.5,...
                    'Color',app.lineColor(dataSetIdx,:),...
                    'Tag','View');
            end
            
            if plotUpVector
                if isgraphics(upVectorHandle)
                    % if line already exist, update
                    upVectorHandle.XData = xPlotUp;
                    upVectorHandle.YData = yPlotUp;
                    upVectorHandle.ZData = zPlotUp;
                else
                    upVectorHandle = line(app.UIAxes3D,xPlotUp,yPlotUp,zPlotUp,...
                        'LineWidth',1.5,...
                        'Color',app.lineColor(dataSetIdx,:),...
                        'LineStyle',':',...
                        'Tag','View');
                end
            end
        end
        
        function animate3DPlot(app)
            if app.AnimationIsRunning
                return
            end
            app.AnimationIsRunning = true;
            app.AnimationRunningLamp.Color = [24, 128, 52]/255; %set light to green
            
            app.disableUIElementsDuringAnimation();
            
            % get frames to animate
            framesToAnimate = app.framesToPlotIdx;
            animationCurser = framesToAnimate(1);
            lim2D = app.UIAxes2D.YLim;
            
            % inital plots 2D
            curserHandle = line(app.UIAxes2D,repmat(double(animationCurser),1,2),lim2D,...
                'Color',app.animationFrameColor,...
                'Tag','Curser',...
                'DisplayName','curr Animation Frame');
            %             curserHandle = findall(app.UIAxes2D,'Tag','Curser');
            
            %initial plot 3D
            app.clear3Dplot()
            
            % animation loop
            while animationCurser < framesToAnimate(end) && ~app.stopAnimation
                %get GUI updates
                animationStep = app.AnimationStepSizeFramesSlider.Value;
                showTrace = app.TracePositonCheckBox.Value;
                
                %update 2D Curser
                set(curserHandle,'xData',repmat(double(animationCurser),1,2));
                
                %plot 3D
                for iDataset = 1:app.nRigidBodys
                    if app.plotDataButton(iDataset).Value == false
                        continue;
                    end
                    app.drawPositionandViewVector(iDataset,animationCurser)
                    %add trace
                    if showTrace
                        app.drawTrace(iDataset,max(1,(animationCurser-animationStep)):animationCurser)
                    end
                end %go through datasets to plot
                animationCurser = animationCurser+animationStep;
                drawnow
            end %while plot loop

            app.AnimationIsRunning = false;
            app.AnimationRunningLamp.Color = [1.00,0.00,0.00];
            app.enableUIElementsAfterAnimation();
            app.stopAnimation = false;
            delete(curserHandle);
        end
    end
    
    % UI Update Methods
    methods
        function enableSwitchesWhenDataIsLoaded(app)
            for element = app.disabledUIElementsDuringLoad
                for i = 1:numel(app.(char(element)))
                    app.(char(element))(i).Enable = true;
                end
            end
        end
        
        function disableSwitchesWhenDataIsLoading(app)
            for element = app.disabledUIElementsDuringLoad
                for i = 1:numel(app.(char(element)))
                    app.(char(element))(i).Enable = false;
                end
            end
        end
        
        function disableUIElementsDuringAnimation(app)
            for element = app.disabledUIElementsDuringAnimation
                for i = 1:numel(app.(char(element)))
                    app.(char(element))(i).Enable = false;
                end
            end
        end
        
        function enableUIElementsAfterAnimation(app)
            for element = app.disabledUIElementsDuringAnimation
                for i = 1:numel(app.(char(element)))
                    app.(char(element))(i).Enable = true;
                end
            end
        end
    end
    
    %helper functions
    methods
        function [xOut,yOut,zOut] = convertTo3DPlotCoordinateSystem(app,xIn,yIn,zIn)
            %check ita_openGL2Matlab for details
            if app.convertOpenGL2Matlab
                xOut = -zIn;
                yOut = -xIn;
                zOut = yIn;
            else
                xOut = xIn;
                yOut = yIn;
                zOut = zIn;
            end
        end
        
        function updateSelection(app,xData)
            %get Values from clicking in plot and update state and plot
            switch app.selectionState
                case 0
                    %add first selection
                    app.selectedFrames = xData;
                case 1
                    %add second selection
                    app.selectedFrames(2) = xData;
                    app.selectedFrames = sort(app.selectedFrames);
                case 2
                    %clear selection
                    app.selectedFrames = [];
            end
            app.selectionState = mod(app.selectionState+1,3);
            app.drawSelectionHighlightIn2DPlot;
            app.draw3DPlot;
        end
        
        function updateSelectforPlotPanelPanel(app)
            %clear rigidBody pushbutton panel
            delete(findobj(app.GridLayoutPlotButtons.Children,'Type','UiStateButton'));
            for iDat = 1:numel(app.logData)
                app.plotDataButton(iDat) = uibutton(app.GridLayoutPlotButtons, 'state');
                app.plotDataButton(iDat).Text = sprintf('ID:%d - %s',app.logData(iDat).rigidBodyID,app.logData(iDat).rigidBodyName);
                app.plotDataButton(iDat).ValueChangedFcn = @(txt,event) ButtonValueChanged(app,event);
                app.plotDataButton(iDat).BackgroundColor = brighten(app.lineColor(iDat,:),.7);
            end
        end
        
        function fillPlotSelect2DDropdown(app)
            selections = cell(1,app.nRigidBodys);
            for iDat = 1:app.nRigidBodys
                selections{iDat} = sprintf('ID:%d - %s',app.logData(iDat).rigidBodyID,app.logData(iDat).rigidBodyName);
            end
            app.DatasetDropDown.Items = selections;
            app.DatasetDropDown.ItemsData = 1:app.nRigidBodys;
        end
        
        function readDataFromFile(app,fileIn)
            disableSwitchesWhenDataIsLoading(app);
            if nargin <2
                %set gui textField
                [fileNameIn,filePathIn]= uigetfile(fullfile(app.filePath,'*.mat'),'Select a itaOptitrack logFile');
                if fileNameIn
                    app.fileName = fileNameIn;
                    app.filePath = filePathIn;
                    app.DataFileTextArea.Value = app.fileName;
                    dataIn = load(fullfile(app.filePath,app.fileName));
                else
                    return;
                end
            else
                dataIn = load(fileIn);
            end
            app.loadData(dataIn);
            enableSwitchesWhenDataIsLoaded(app);
            
        end
        
        function loadData(app,dataIn)
            %update data
            if isstruct(dataIn) && isfield(dataIn,'LogData')
                app.logData = dataIn.LogData;
            else
                error('Can''t find LogData');
            end
            
            app.updateSelectforPlotPanelPanel();
            app.fillPlotSelect2DDropdown();
            app.draw2DPlot();
        end
        
        function frameIdx = framesToPlotIdx(app)
            % check switch if all or only selected are to be plotted
            switch app.Plot3DSwitch.Value
                case 'All'
                    lim = [1,app.nDataPoints];
                case 'Selection'
                    % 'get idx dependent on state of selection
                    switch app.selectionState
                        case 0
                            %none selected -> plot all
                            lim = [1,app.nDataPoints];
                        case 1
                            %one selection -> use as starting index
                            lim = [app.selectedFrames,app.nDataPoints];
                        case 2
                            %2 selections -> plot in between values
                            lim = app.selectedFrames;
                    end
            end
            frameIdx = int32(lim(1):lim(2));
        end
    end
    
    %get/set methods
    methods
        function value = get.convertOpenGL2Matlab(app)
            value = strcmp(app.CoordinateSystemSwitch.Value,'Matlab');
        end
        
        function value = get.nRigidBodys(app)
            value = numel(app.logData);
        end
        
        function value = get.nDataPoints(app)
            value = app.logData(1).position.nPoints;
        end
        
        function idx = get.plotDataset2D(app)
            idx = app.DatasetDropDown.Value;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fileIn)
            % check that ita-toolbox
            if ~exist('ita_toolbox_path.m','file')
                errordlg("ita-toolbox not installed. Please go to ita-toolbox.org to install the Toolbox")
            end
            
            % set colors of checkboxes
            app.RollCheckBox.FontColor = app.lineColor(1,:);
            app.PitchCheckBox.FontColor = app.lineColor(2,:);
            app.YawCheckBox.FontColor = app.lineColor(3,:);
            
            if nargin < 2
                app.plotDataButton.Text = 'Please Load Data First';
                app.disableSwitchesWhenDataIsLoading;
                return
            end
            
            % fileIn is data struct to fill appData or str with file and
            % folderName
            if isa(fileIn,'struct')
                if isfield(fileIn,'LogData') % case 1: direct read of saved matfile
                    app.loadData(fileIn)
                elseif isfield(fileIn,'rigidBodyName') &&... %% case 2: fileIn is logData
                        isfield(fileIn,'position') &&...
                        isfield(fileIn,'orientation')
                dataToLoad = struct('LogData',fileIn);
                app.loadData(dataToLoad);
                end
            elseif isa(fileIn,'char') || isa(fileIn,'string')
                if ~exist(fileIn,'file')
                    error('File: <<%s>> was NOT found!')
                end
                app.readDataFromFile(fileIn);
            else
                error('Couldn''t parse provided data')
            end
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            readDataFromFile(app);
            app.LoadDataButton.BackgroundColor = [0.96,0.96,0.96]; %visual hint at startup
        end

        % Callback function
        function ButtonValueChanged(app, event)
            draw3DPlot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {708, 708};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {245, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: CoordinateSystemSwitch, DatasetDropDown, 
        % KeepAxisMAXCheckBox, Plot3DSwitch, UpdatePlotsButton, 
        % showGridCheckBox
        function UpdatePlotsButtonPushed(app, event)
            updateAllPlots(app);
        end

        % Value changed function: LegendCheckBox, PitchCheckBox, 
        % RollCheckBox, YawCheckBox
        function RollCheckBoxValueChanged(app, event)
            draw2DPlot(app);
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            app.animate3DPlot();
        end

        % Button down function: UIAxes2D
        function UIAxes2DButtonDown(app, event)
            x = app.UIAxes2D.CurrentPoint(1);
            app.updateSelection(x);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopAnimation = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Select a itaOptitrack save file (.mat file containing logData and logInfo)'};
            app.LoadDataButton.Text = 'Load Data';
            app.DataFileTextAreaLabel = uilabel(app.LeftPanel);
            app.DataFileTextAreaLabel.Enable = 'off';
            app.DataFileTextAreaLabel.Text = 'Data File:';
            app.DataFileTextArea = uitextarea(app.LeftPanel);
            app.DataFileTextArea.Editable = 'off';
            app.DataFileTextArea.Enable = 'off';
            app.DPlotSettingsPanel = uipanel(app.LeftPanel);
            app.DPlotSettingsPanel.Title = '3D Plot Settings';
            app.GridLayoutPlotButtons = uigridlayout(app.DPlotSettingsPanel);
            app.plotDataButton = uibutton(app.GridLayoutPlotButtons, 'state');
            app.plotDataButton.Enable = 'off';
            app.plotDataButton.Text = 'plotData';
            app.Plot3DSwitch = uiswitch(app.GridLayoutPlotButtons, 'slider');
            app.Plot3DSwitch.Items = {'All', 'Selection'};
            app.Plot3DSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.Plot3DSwitch.Tooltip = {'Plot all frames or only the ones selected in the 2D plot'};
            app.Plot3DSwitch.Value = 'Selection';
            app.FramestoPlotLabel = uilabel(app.GridLayoutPlotButtons);
            app.FramestoPlotLabel.Text = 'Frames to Plot:';
            app.CoordinatesystemLabel = uilabel(app.GridLayoutPlotButtons);
            app.CoordinatesystemLabel.Text = 'Coordinatesystem';
            app.CoordinateSystemSwitch = uiswitch(app.GridLayoutPlotButtons, 'slider');
            app.CoordinateSystemSwitch.Items = {'Matlab', 'OpenGL'};
            app.CoordinateSystemSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.CoordinateSystemSwitch.Tooltip = {'Plot 3D Data in Matlab or OpenGL(rh) coordinate system'};
            app.CoordinateSystemSwitch.Value = 'Matlab';
            app.KeepAxisMAXCheckBox = uicheckbox(app.GridLayoutPlotButtons);
            app.KeepAxisMAXCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.KeepAxisMAXCheckBox.Tooltip = {'If active, x y z limits increase when new data is added, but keeps max values when showing a smaller selection for better compareability'};
            app.KeepAxisMAXCheckBox.Text = 'Keep Axis MAX';
            app.KeepAxisMAXCheckBox.Value = true;
            app.EditFieldLabel = uilabel(app.GridLayoutPlotButtons);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.GridLayoutPlotButtons, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.GridLayoutPlotButtons, 'text');
            app.EditField_2.Editable = 'off';
            app.showGridCheckBox = uicheckbox(app.GridLayoutPlotButtons);
            app.showGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.showGridCheckBox.Tooltip = {'If active, x y z limits increase when new data is added, but keeps max values when showing a smaller selection for better compareability'};
            app.showGridCheckBox.Text = 'show Grid';
            app.showGridCheckBox.Value = true;
            app.UpdatePlotsButton = uibutton(app.LeftPanel, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.UpdatePlotsButton.Enable = 'off';
            app.UpdatePlotsButton.Visible = 'off';
            app.UpdatePlotsButton.Text = 'UpdatePlots';
            app.PlotSettings_2D = uipanel(app.LeftPanel);
            app.PlotSettings_2D.Title = '2D Plot Settings';
            app.GridLayout2 = uigridlayout(app.PlotSettings_2D);
            app.RollCheckBox = uicheckbox(app.GridLayout2);
            app.RollCheckBox.ValueChangedFcn = createCallbackFcn(app, @RollCheckBoxValueChanged, true);
            app.RollCheckBox.Text = 'Roll';
            app.PitchCheckBox = uicheckbox(app.GridLayout2);
            app.PitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @RollCheckBoxValueChanged, true);
            app.PitchCheckBox.Text = 'Pitch';
            app.PitchCheckBox.Value = true;
            app.YawCheckBox = uicheckbox(app.GridLayout2);
            app.YawCheckBox.ValueChangedFcn = createCallbackFcn(app, @RollCheckBoxValueChanged, true);
            app.YawCheckBox.Text = 'Yaw';
            app.LegendCheckBox = uicheckbox(app.GridLayout2);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @RollCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.DatasetDropDown = uidropdown(app.GridLayout2);
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.DatasetDropDown.Tooltip = {'Select Rigid Body to plot'};
            app.Panel = uipanel(app.LeftPanel);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.AnimateButton = uibutton(app.GridLayout4, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.AnimationRunningLamp = uilamp(app.GridLayout4);
            app.AnimationRunningLamp.Tooltip = {'Green: Animation is running'; 'Red: Animation is stopped -> showing data according to swich all <selection>'};
            app.StopButton = uibutton(app.GridLayout4, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AnimationStepSizeFramesSliderLabel = uilabel(app.LeftPanel);
            app.AnimationStepSizeFramesSliderLabel.Text = 'Animation Step Size [Frames]';
            app.AnimationStepSizeFramesSlider = uislider(app.LeftPanel);
            app.AnimationStepSizeFramesSlider.Limits = [1 100];
            app.AnimationStepSizeFramesSlider.MajorTicks = [1 20 40 60 80 100];
            app.AnimationStepSizeFramesSlider.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.AnimationStepSizeFramesSlider.Tooltip = {''};
            app.AnimationStepSizeFramesSlider.Value = 10;
            app.TracePositonCheckBox = uicheckbox(app.LeftPanel);
            app.TracePositonCheckBox.Tooltip = {'Animation keeps a trace of the position data'};
            app.TracePositonCheckBox.Text = 'Trace Positon';
            app.TracePositonCheckBox.Value = true;
            app.UpVectorCheckBox = uicheckbox(app.LeftPanel);
            app.UpVectorCheckBox.Tooltip = {'Animation keeps a trace of the position data'};
            app.UpVectorCheckBox.Text = 'Up-Vector';
            app.UpVectorCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes2D = uiaxes(app.GridLayout3);
            title(app.UIAxes2D, 'Orientation over Time (Click to Select Frames to Plot)')
            xlabel(app.UIAxes2D, 'Time in [frames]')
            ylabel(app.UIAxes2D, 'Angle in [degree]')
            app.UIAxes2D.ButtonDownFcn = createCallbackFcn(app, @UIAxes2DButtonDown, true);
            app.UIAxes3D = uiaxes(app.GridLayout3);
            title(app.UIAxes3D, 'Position and Orientation')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = itaOptitrackVisualizer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end